classdef GasTurbineCycleAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        ResultsPanel                   matlab.ui.container.Panel
        ResultsGridLayout              matlab.ui.container.GridLayout
        ResultsTabGroup                matlab.ui.container.TabGroup
        StationDataTab                 matlab.ui.container.Tab
        StationTable                   matlab.ui.control.Table
        PlotsTab                       matlab.ui.container.Tab
        PerformanceAxes                matlab.ui.control.UIAxes
        AboutTab                       matlab.ui.container.Tab
        AboutTextArea                  matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        SummaryPanel                   matlab.ui.container.Panel
        SummaryGridLayout              matlab.ui.container.GridLayout
        ExitVelocityValueLabel         matlab.ui.control.Label
        FuelRatioValueLabel            matlab.ui.control.Label
        SpecificThrustValueLabel       matlab.ui.control.Label
        NozzleStatusValueLabel         matlab.ui.control.Label
        TSFCValueLabel                 matlab.ui.control.Label
        ExitvelocityLabel              matlab.ui.control.Label
        FuelAirRatioLabel              matlab.ui.control.Label
        SpecificThrustLabel            matlab.ui.control.Label
        ThrustValueLabel               matlab.ui.control.Label
        NozzleConditionLabel           matlab.ui.control.Label
        TSFCLabel                      matlab.ui.control.Label
        NetThrustLabel                 matlab.ui.control.Label
        InputsPanel                    matlab.ui.container.Panel
        InputsGridLayout               matlab.ui.container.GridLayout
        NozzlePressureRecoveryLabel    matlab.ui.control.Label
        CombustorEfficiencyLabel       matlab.ui.control.Label
        TurbineEfficiencyLabel         matlab.ui.control.Label
        CompressorEfficiencyLabel      matlab.ui.control.Label
        TurbineTemperatureLabel        matlab.ui.control.Label
        CompressorPRLabel              matlab.ui.control.Label
        MassFlowLabel                  matlab.ui.control.Label
        MachLabel                      matlab.ui.control.Label
        AltitudeLabel                  matlab.ui.control.Label
        ExportResultsButton            matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        GraphTypeDropDown              matlab.ui.control.DropDown
        GraphtypeDropDownLabel         matlab.ui.control.Label
        EngineTypeDropDown             matlab.ui.control.DropDown
        EnginetypeDropDownLabel        matlab.ui.control.Label
        NozzlePressureRecoveryEditField  matlab.ui.control.NumericEditField
        NozzlepressurerecoveryEditFieldLabel  matlab.ui.control.Label
        CombustorEfficiencyEditField   matlab.ui.control.NumericEditField
        CombustorefficiencyEditFieldLabel  matlab.ui.control.Label
        TurbineEfficiencyEditField     matlab.ui.control.NumericEditField
        TurbineefficiencyEditFieldLabel  matlab.ui.control.Label
        CompressorEfficiencyEditField  matlab.ui.control.NumericEditField
        CompressorefficiencyEditFieldLabel  matlab.ui.control.Label
        TurbineTemperatureEditField    matlab.ui.control.NumericEditField
        TurbineinlettemperatureEditFieldLabel  matlab.ui.control.Label
        CompressorPREditField          matlab.ui.control.NumericEditField
        CompressorpressureratioEditFieldLabel  matlab.ui.control.Label
        MassFlowEditField              matlab.ui.control.NumericEditField
        AirmassflowEditFieldLabel      matlab.ui.control.Label
        MachEditField                  matlab.ui.control.NumericEditField
        MachnumberEditFieldLabel       matlab.ui.control.Label
        AltitudeEditField              matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel         matlab.ui.control.Label
        EngineParametersLabel          matlab.ui.control.Label
        FlightConditionsLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentResult % Description
        CurrentInput
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the app title
            app.UIFigure.Name = 'Gas Turbine Cycle Analysis Tool';
            % Make the information read-only
            app.ExportResultsButton.Enable = 'off';
            app.AboutTextArea.Editable = 'off';

            app.EngineTypeDropDown.Value = 'Turbojet';
            app.EngineTypeDropDown.Enable = 'off';

            % Populate the Assumptions & Validation tab
            app.AboutTextArea.Value = { ...
                'GAS TURBINE CYCLE ANALYSIS TOOL'
                'Version 1.0'
                ''
                'MODEL ASSUMPTIONS'
                '• Steady, one-dimensional, single-spool turbojet cycle.'
                '• Standard-atmosphere model valid from 0 to 20 km.'
                '• Constant specific heats and heat-capacity ratios.'
                '• Separate properties are used for air and combustion gas.'
                '• Adiabatic diffuser with prescribed total-pressure recovery.'
                '• Compressor and turbine use prescribed isentropic efficiencies.'
                '• Combustor includes fuel addition, efficiency, and pressure loss.'
                '• Turbine power is matched to compressor power.'
                '• Convergent nozzle with automatic choking detection.'
                '• Cooling, bleed, accessory loads, and afterburning are excluded.'
                ''
                'PARAMETRIC SWEEPS'
                '• Pressure-ratio and turbine-temperature sweeps keep other inputs fixed.'
                '• Altitude sweep assumes constant Mach number and inlet capture area.'
                '• Mach sweep assumes constant altitude and inlet capture area.'
                '• Sweeps are sensitivity studies, not complete off-design predictions.'
                '• Compressor and turbine performance maps are not included.'
                ''
                'VERIFICATION'
                '• Turbine-compressor shaft-power balance verified to machine precision.'
                '• Compressor and turbine efficiencies independently recovered.'
                '• Nozzle choking condition and exit Mach number verified.'
                '• Net thrust reconstructed from momentum and pressure contributions.'
                ''
                'VALIDATION'
                '• Atmosphere model compared with NASA Glenn atmosphere equations.'
                '• Specific net thrust compared with NASA EngineSim.'
                '• MATLAB prediction: approximately 725.6 N/(kg/s).'
                '• Corrected EngineSim estimate: approximately 710 N/(kg/s).'
                '• Difference: approximately 2.2%.'
                ''
                'PURPOSE AND LIMITATIONS'
                '• Intended for educational and preliminary cycle analysis.'
                '• Not intended to replace certified engine-performance software.'
                };
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Button pushed function: CalculateButton
%function CalculateButtonPushed(app, event)

     try
        %% Read user inputs

        in = struct();

        in.altitude = app.AltitudeEditField.Value;
        in.M0       = app.MachEditField.Value;
        in.mdotAir  = app.MassFlowEditField.Value;

        in.pi_c = app.CompressorPREditField.Value;
        in.Tt4  = app.TurbineTemperatureEditField.Value;

        in.eta_c = app.CompressorEfficiencyEditField.Value;
        in.eta_t = app.TurbineEfficiencyEditField.Value;
        in.eta_b = app.CombustorEfficiencyEditField.Value;
        in.pi_n  = app.NozzlePressureRecoveryEditField.Value;

        %% Run the turbojet solver

        result = turbojetCycle(in);

        %% Store the latest input and output

        app.CurrentInput  = in;
        app.CurrentResult = result;
        app.ExportResultsButton.Enable = 'on';

        %% Display performance results

        app.ThrustValueLabel.Text = ...
            sprintf('%.2f kN', result.thrust/1000);

        app.SpecificThrustValueLabel.Text = ...
            sprintf('%.1f N/(kg/s)', result.specificThrust);

        app.TSFCValueLabel.Text = ...
            sprintf('%.4f kg/(N h)', result.TSFC);

        app.FuelRatioValueLabel.Text = ...
            sprintf('%.5f', result.fuelAirRatio);

        app.ExitVelocityValueLabel.Text = ...
            sprintf('%.1f m/s', result.exitVelocity);

        if result.nozzleChoked
            app.NozzleStatusValueLabel.Text = 'Choked';
        else
            app.NozzleStatusValueLabel.Text = 'Unchoked';
        end

        %% Display station table

        app.StationTable.Data = result.stationTable;

        app.StationTable.ColumnName = { ...
            'Station', ...
            'Location', ...
            'Total temperature [K]', ...
            'Total pressure [kPa]'};

        app.StationTable.ColumnWidth = { ...
            70, ...
            '1x', ...
            150, ...
            150};

        %% Generate the selected graph

        GraphTypeDropDownValueChanged(app, []);

     catch ME

         %% Clear results from any previous successful calculation

         app.CurrentInput = [];
         app.CurrentResult = [];

         app.ThrustValueLabel.Text = '—';
         app.SpecificThrustValueLabel.Text = '—';
         app.TSFCValueLabel.Text = '—';
         app.FuelRatioValueLabel.Text = '—';
         app.NozzleStatusValueLabel.Text = '—';
         app.ExitVelocityValueLabel.Text = '—';

         app.StationTable.Data = table();

         app.ExportResultsButton.Enable = 'off';

         cla(app.PerformanceAxes);
         legend(app.PerformanceAxes, 'off');
         title(app.PerformanceAxes, '');
         xlabel(app.PerformanceAxes, '');
         ylabel(app.PerformanceAxes, '');
         grid(app.PerformanceAxes, 'off');

         %% Show the actual calculation error

         uialert( ...
             app.UIFigure, ...
             ME.message, ...
             'Calculation Error', ...
             'Icon', 'error');

    end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %% Restore default inputs
            disp('Reset callback clicked')
            app.AltitudeEditField.Value = 10000;
            app.MachEditField.Value = 0.80;
            app.MassFlowEditField.Value = 25;

            app.CompressorPREditField.Value = 12;
            app.TurbineTemperatureEditField.Value = 1400;

            app.CompressorEfficiencyEditField.Value = 0.86;
            app.TurbineEfficiencyEditField.Value = 0.89;
            app.CombustorEfficiencyEditField.Value = 0.99;
            app.NozzlePressureRecoveryEditField.Value = 0.99;

            app.EngineTypeDropDown.Value = 'Turbojet';
            app.GraphTypeDropDown.Value = 'Station Temperature';

            %% Clear displayed results

            app.ThrustValueLabel.Text = '—';
            app.SpecificThrustValueLabel.Text = '—';
            app.TSFCValueLabel.Text = '—';
            app.FuelRatioValueLabel.Text = '—';
            app.NozzleStatusValueLabel.Text = '—';
            app.ExitVelocityValueLabel.Text = '—';

            %% Clear table and graph

            app.StationTable.Data = table();
            app.CurrentInput = [];
            app.CurrentResult = [];
           

            app.CurrentResult = [];

            app.ExportResultsButton.Enable = 'off';

            cla(app.PerformanceAxes);
            title(app.PerformanceAxes, '');
            xlabel(app.PerformanceAxes, '');
            ylabel(app.PerformanceAxes, '');
            grid(app.PerformanceAxes, 'off');
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            %% Confirm that a calculation has been completed

            if isempty(app.CurrentResult)

                uialert( ...
                    app.UIFigure, ...
                    'Please run a calculation before exporting results.', ...
                    'No Results', ...
                    'Icon', 'warning');

                return
            end

            %% Ask the user where to save the file

            [fileName, folderName] = uiputfile( ...
                '*.xlsx', ...
                'Save Cycle Analysis Results', ...
                'TurbojetCycleResults.xlsx');

            % User selected Cancel
            if isequal(fileName, 0)
                return
            end

            fullFileName = fullfile(folderName, fileName);

            %% Create summary table

            result = app.CurrentResult;

            if result.nozzleChoked
                nozzleCondition = "Choked";
            else
                nozzleCondition = "Unchoked";
            end

            summaryTable = table( ...
                result.thrust/1000, ...
                result.specificThrust, ...
                result.TSFC, ...
                result.fuelAirRatio, ...
                result.exitVelocity, ...
                result.exitMach, ...
                nozzleCondition, ...
                result.compressorPower/1e6, ...
                result.turbinePower/1e6, ...
                'VariableNames', { ...
                'NetThrust_kN', ...
                'SpecificThrust_N_per_kg_s', ...
                'TSFC_kg_per_N_h', ...
                'FuelAirRatio', ...
                'ExitVelocity_m_per_s', ...
                'ExitMachNumber', ...
                'NozzleCondition', ...
                'CompressorPower_MW', ...
                'TurbinePower_MW'});

            %% Export summary and station data

            try

                writetable( ...
                    summaryTable, ...
                    fullFileName, ...
                    'Sheet', 'Performance Summary');

                writetable( ...
                    result.stationTable, ...
                    fullFileName, ...
                    'Sheet', 'Station Data');

                uialert( ...
                    app.UIFigure, ...
                    'Results exported successfully.', ...
                    'Export Complete', ...
                    'Icon', 'success');

            catch ME

                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Export Error', ...
                    'Icon', 'error');

            end
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            % A calculation must be completed before plotting
            if isempty(app.CurrentResult) || isempty(app.CurrentInput)
                return
            end

            result = app.CurrentResult;
            stationNumber = 1:height(result.stationTable);

            % Clear the previous graph
            cla(app.PerformanceAxes);
            legend(app.PerformanceAxes, 'off');

            % Restore automatic axes settings before drawing a new graph
            app.PerformanceAxes.XTickMode = 'auto';
            app.PerformanceAxes.XTickLabelMode = 'auto';
            app.PerformanceAxes.XLimMode = 'auto';
            app.PerformanceAxes.YLimMode = 'auto';

            switch app.GraphTypeDropDown.Value

                case 'Station Temperature'

                    plot( ...
                        app.PerformanceAxes, ...
                        stationNumber, ...
                        result.stationTable.TotalTemperature_K, ...
                        '-o', ...
                        'LineWidth', 1.5);

                    app.PerformanceAxes.XTick = stationNumber;
                    app.PerformanceAxes.XTickLabel = ...
                        cellstr(result.stationTable.Station);

                    xlabel(app.PerformanceAxes, 'Engine station');
                    ylabel(app.PerformanceAxes, 'Total temperature [K]');

                    title(app.PerformanceAxes, ...
                        'Total Temperature Through the Engine');


                case 'Station Pressure'

                    plot( ...
                        app.PerformanceAxes, ...
                        stationNumber, ...
                        result.stationTable.TotalPressure_kPa, ...
                        '-o', ...
                        'LineWidth', 1.5);

                    app.PerformanceAxes.XTick = stationNumber;
                    app.PerformanceAxes.XTickLabel = ...
                        cellstr(result.stationTable.Station);

                    xlabel(app.PerformanceAxes, 'Engine station');
                    ylabel(app.PerformanceAxes, 'Total pressure [kPa]');

                    title(app.PerformanceAxes, ...
                        'Total Pressure Through the Engine');


                case 'Pressure-Ratio Sweep'

                    % Pressure-ratio range
                    pressureRatio = linspace(2, 30, 50);
                    thrust_kN = nan(size(pressureRatio));

                    % Run one cycle calculation for each pressure ratio
                    for k = 1:numel(pressureRatio)

                        sweepInput = app.CurrentInput;
                        sweepInput.pi_c = pressureRatio(k);

                        try
                            sweepResult = turbojetCycle(sweepInput);
                            thrust_kN(k) = sweepResult.thrust/1000;

                        catch
                            % Invalid operating points remain NaN
                        end
                    end

                    validPoint = isfinite(thrust_kN);

                    if ~any(validPoint)

                        uialert( ...
                            app.UIFigure, ...
                            'No valid pressure-ratio points were found.', ...
                            'Sweep Error', ...
                            'Icon', 'warning');

                        return
                    end

                    % Plot sweep
                    plot( ...
                        app.PerformanceAxes, ...
                        pressureRatio(validPoint), ...
                        thrust_kN(validPoint), ...
                        '-', ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'on');

                    % Mark current input condition
                    plot( ...
                        app.PerformanceAxes, ...
                        app.CurrentInput.pi_c, ...
                        app.CurrentResult.thrust/1000, ...
                        'o', ...
                        'MarkerSize', 8, ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'off');

                    xlabel(app.PerformanceAxes, ...
                        'Compressor pressure ratio');

                    ylabel(app.PerformanceAxes, ...
                        'Net thrust [kN]');

                    title(app.PerformanceAxes, ...
                        'Effect of Compressor Pressure Ratio on Thrust');
                    xlim(app.PerformanceAxes, [2 30]);

                    legend( ...
                        app.PerformanceAxes, ...
                        {'Pressure-ratio sweep', ...
                        'Current operating point'}, ...
                        'Location', 'best');

                case 'Altitude Sweep'

                    %% Define altitude range

                    altitude_m  = linspace(0, 20000, 81);
                    altitude_km = altitude_m/1000;

                    thrust_kN = nan(size(altitude_m));

                    %% Reference atmospheric condition

                    referenceAtmosphere = ...
                        isaAtmosphere(app.CurrentInput.altitude);

                    referenceFlowParameter = ...
                        referenceAtmosphere.rho * ...
                        sqrt(referenceAtmosphere.T);

                    %% Run the cycle at each altitude

                    for k = 1:numel(altitude_m)

                        sweepInput = app.CurrentInput;
                        sweepInput.altitude = altitude_m(k);

                        try
                            atmosphere = isaAtmosphere(altitude_m(k));

                            % Approximate mass-flow correction for fixed inlet area
                            % and constant flight Mach number
                            flowParameter = ...
                                atmosphere.rho * sqrt(atmosphere.T);

                            massFlowRatio = ...
                                flowParameter/referenceFlowParameter;

                            sweepInput.mdotAir = ...
                                app.CurrentInput.mdotAir * massFlowRatio;

                            sweepResult = turbojetCycle(sweepInput);

                            thrust_kN(k) = ...
                                sweepResult.thrust/1000;

                        catch
                            % Invalid points remain NaN
                        end

                    end

                    %% Retain valid operating points

                    validPoint = isfinite(thrust_kN);

                    if ~any(validPoint)

                        uialert( ...
                            app.UIFigure, ...
                            'No valid altitude-sweep points were found.', ...
                            'Sweep Error', ...
                            'Icon', 'warning');

                        return

                    end

                    %% Plot altitude sweep

                    plot( ...
                        app.PerformanceAxes, ...
                        altitude_km(validPoint), ...
                        thrust_kN(validPoint), ...
                        '-', ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'on');

                    %% Mark the current operating condition

                    plot( ...
                        app.PerformanceAxes, ...
                        app.CurrentInput.altitude/1000, ...
                        app.CurrentResult.thrust/1000, ...
                        'o', ...
                        'MarkerSize', 8, ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'off');

                    xlabel(app.PerformanceAxes, ...
                        'Altitude [km]');

                    ylabel(app.PerformanceAxes, ...
                        'Net thrust [kN]');

                    title(app.PerformanceAxes, ...
                        'Altitude Effect on Thrust at Constant Mach and Inlet Area');

                    xlim(app.PerformanceAxes, [0 20]);

                    legend( ...
                        app.PerformanceAxes, ...
                        {'Altitude sweep', ...
                        'Current operating point'}, ...
                        'Location', 'best');


                case 'Mach-Number Sweep'

                    %% Ensure the reference Mach number is valid

                    if app.CurrentInput.M0 <= 0

                        uialert( ...
                            app.UIFigure, ...
                            ['The reference Mach number must be greater than zero ' ...
                            'to perform a fixed-inlet-area Mach sweep.'], ...
                            'Mach Sweep Error', ...
                            'Icon', 'warning');

                        return

                    end

                    %% Define Mach-number range

                    machNumber = linspace(0.05, 1.50, 60);
                    thrust_kN = nan(size(machNumber));

                    %% Determine the inlet capture area from the current condition

                    atmosphere = isaAtmosphere(app.CurrentInput.altitude);

                    gammaAir = 1.40;
                    R = 287.05;

                    speedOfSound = sqrt( ...
                        gammaAir*R*atmosphere.T);

                    referenceVelocity = ...
                        app.CurrentInput.M0*speedOfSound;

                    inletArea = ...
                        app.CurrentInput.mdotAir / ...
                        (atmosphere.rho*referenceVelocity);

                    %% Run the cycle at each Mach number

                    for k = 1:numel(machNumber)

                        sweepInput = app.CurrentInput;
                        sweepInput.M0 = machNumber(k);

                        flightVelocity = ...
                            machNumber(k)*speedOfSound;

                        % Mass flow for fixed altitude and inlet capture area
                        sweepInput.mdotAir = ...
                            atmosphere.rho*flightVelocity*inletArea;

                        try
                            sweepResult = turbojetCycle(sweepInput);

                            thrust_kN(k) = ...
                                sweepResult.thrust/1000;

                        catch
                            % Invalid operating points remain NaN
                        end

                    end

                    %% Retain valid results

                    validPoint = isfinite(thrust_kN);

                    if ~any(validPoint)

                        uialert( ...
                            app.UIFigure, ...
                            'No valid Mach-number sweep points were found.', ...
                            'Sweep Error', ...
                            'Icon', 'warning');

                        return

                    end

                    %% Plot Mach-number sweep

                    plot( ...
                        app.PerformanceAxes, ...
                        machNumber(validPoint), ...
                        thrust_kN(validPoint), ...
                        '-', ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'on');

                    %% Mark the selected operating point

                    plot( ...
                        app.PerformanceAxes, ...
                        app.CurrentInput.M0, ...
                        app.CurrentResult.thrust/1000, ...
                        'o', ...
                        'MarkerSize', 8, ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'off');

                    xlabel(app.PerformanceAxes, ...
                        'Flight Mach number');

                    ylabel(app.PerformanceAxes, ...
                        'Net thrust [kN]');

                    title(app.PerformanceAxes, ...
                        'Effect of Mach Number on Thrust at Constant Altitude and Inlet Area');

                    xlim(app.PerformanceAxes, [0 1.5]);

                    legend( ...
                        app.PerformanceAxes, ...
                        {'Mach-number sweep', ...
                        'Selected operating point'}, ...
                        'Location', 'best');


                    %%%%%%%%
                case 'Turbine-Temperature Sweep'

                    %% Define turbine inlet temperature range

                    turbineTemperature_K = linspace(800, 1800, 81);
                    thrust_kN = nan(size(turbineTemperature_K));

                    %% Run the cycle at each turbine inlet temperature

                    for k = 1:numel(turbineTemperature_K)

                        sweepInput = app.CurrentInput;
                        sweepInput.Tt4 = turbineTemperature_K(k);

                        try
                            sweepResult = turbojetCycle(sweepInput);

                            thrust_kN(k) = ...
                                sweepResult.thrust/1000;

                        catch
                            % Temperatures that produce invalid operating
                            % conditions remain NaN.
                        end

                    end

                    %% Retain valid operating points

                    validPoint = isfinite(thrust_kN);

                    if ~any(validPoint)

                        uialert( ...
                            app.UIFigure, ...
                            ['No valid turbine-temperature sweep points ' ...
                            'were found.'], ...
                            'Sweep Error', ...
                            'Icon', 'warning');

                        return

                    end

                    %% Plot turbine-temperature sweep

                    plot( ...
                        app.PerformanceAxes, ...
                        turbineTemperature_K(validPoint), ...
                        thrust_kN(validPoint), ...
                        '-', ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'on');

                    %% Mark the selected operating point

                    plot( ...
                        app.PerformanceAxes, ...
                        app.CurrentInput.Tt4, ...
                        app.CurrentResult.thrust/1000, ...
                        'o', ...
                        'MarkerSize', 8, ...
                        'LineWidth', 1.5);

                    hold(app.PerformanceAxes, 'off');

                    xlabel(app.PerformanceAxes, ...
                        'Turbine inlet temperature [K]');

                    ylabel(app.PerformanceAxes, ...
                        'Net thrust [kN]');

                    title(app.PerformanceAxes, ...
                        ['Effect of Turbine Inlet Temperature on Thrust ' ...
                        'at Fixed Flight Conditions']);

                    xlim(app.PerformanceAxes, [800 1800]);

                    legend( ...
                        app.PerformanceAxes, ...
                        {'Temperature sweep', ...
                        'Selected operating point'}, ...
                        'Location', 'best');

            end

            grid(app.PerformanceAxes, 'on');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Gas Turbine Cycle Analysis Tool';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.InputsPanel = uipanel(app.MainGridLayout);
            app.InputsPanel.Title = 'Cycle Inputs';
            app.InputsGridLayout = uigridlayout(app.InputsPanel);
            app.FlightConditionsLabel = uilabel(app.InputsGridLayout);
            app.FlightConditionsLabel.Text = 'Flight Conditions';
            app.EngineParametersLabel = uilabel(app.InputsGridLayout);
            app.EngineParametersLabel.Text = 'Engine Parameters';
            app.AltitudeEditFieldLabel = uilabel(app.InputsGridLayout);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.AltitudeEditField.Limits = [0 20000];
            app.AltitudeEditField.Value = 10000;
            app.MachnumberEditFieldLabel = uilabel(app.InputsGridLayout);
            app.MachnumberEditFieldLabel.Text = 'Mach number';
            app.MachEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.MachEditField.Limits = [0 3];
            app.MachEditField.Value = 0.8;
            app.AirmassflowEditFieldLabel = uilabel(app.InputsGridLayout);
            app.AirmassflowEditFieldLabel.Text = 'Air mass flow';
            app.MassFlowEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.MassFlowEditField.Limits = [0.01 Inf];
            app.MassFlowEditField.Value = 25;
            app.CompressorpressureratioEditFieldLabel = uilabel(app.InputsGridLayout);
            app.CompressorpressureratioEditFieldLabel.Text = 'Compressor pressure ratio';
            app.CompressorPREditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.CompressorPREditField.Limits = [1.01 50];
            app.CompressorPREditField.Value = 12;
            app.TurbineinlettemperatureEditFieldLabel = uilabel(app.InputsGridLayout);
            app.TurbineinlettemperatureEditFieldLabel.Text = 'Turbine inlet temperature';
            app.TurbineTemperatureEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.TurbineTemperatureEditField.Limits = [500 2500];
            app.TurbineTemperatureEditField.Value = 1400;
            app.CompressorefficiencyEditFieldLabel = uilabel(app.InputsGridLayout);
            app.CompressorefficiencyEditFieldLabel.Text = 'Compressor efficiency';
            app.CompressorEfficiencyEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.CompressorEfficiencyEditField.Limits = [0.01 1];
            app.CompressorEfficiencyEditField.Value = 0.86;
            app.TurbineefficiencyEditFieldLabel = uilabel(app.InputsGridLayout);
            app.TurbineefficiencyEditFieldLabel.Text = 'Turbine efficiency';
            app.TurbineEfficiencyEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.TurbineEfficiencyEditField.Limits = [0.01 1];
            app.TurbineEfficiencyEditField.Value = 0.89;
            app.CombustorefficiencyEditFieldLabel = uilabel(app.InputsGridLayout);
            app.CombustorefficiencyEditFieldLabel.Text = 'Combustor efficiency';
            app.CombustorEfficiencyEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.CombustorEfficiencyEditField.Limits = [0.01 1];
            app.CombustorEfficiencyEditField.Value = 0.99;
            app.NozzlepressurerecoveryEditFieldLabel = uilabel(app.InputsGridLayout);
            app.NozzlepressurerecoveryEditFieldLabel.Text = 'Nozzle pressure recovery';
            app.NozzlePressureRecoveryEditField = uieditfield(app.InputsGridLayout, 'numeric');
            app.NozzlePressureRecoveryEditField.Limits = [0.01 1];
            app.NozzlePressureRecoveryEditField.Value = 0.99;
            app.EnginetypeDropDownLabel = uilabel(app.InputsGridLayout);
            app.EnginetypeDropDownLabel.Text = 'Engine type';
            app.EngineTypeDropDown = uidropdown(app.InputsGridLayout);
            app.EngineTypeDropDown.Items = {'Turbojet'};
            app.EngineTypeDropDown.Value = 'Turbojet';
            app.GraphtypeDropDownLabel = uilabel(app.InputsGridLayout);
            app.GraphtypeDropDownLabel.Text = 'Graph type';
            app.GraphTypeDropDown = uidropdown(app.InputsGridLayout);
            app.GraphTypeDropDown.Items = {'Station Temperature', 'Station Pressure', 'Pressure-Ratio Sweep', 'Altitude Sweep', 'Mach-Number Sweep', 'Turbine-Temperature Sweep'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Station Temperature';
            app.CalculateButton = uibutton(app.InputsGridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.InputsGridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportResultsButton = uibutton(app.InputsGridLayout, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.AltitudeLabel = uilabel(app.InputsGridLayout);
            app.AltitudeLabel.Text = 'm';
            app.MachLabel = uilabel(app.InputsGridLayout);
            app.MachLabel.Text = '-';
            app.MassFlowLabel = uilabel(app.InputsGridLayout);
            app.MassFlowLabel.Text = 'kg/s';
            app.CompressorPRLabel = uilabel(app.InputsGridLayout);
            app.CompressorPRLabel.Text = '-';
            app.TurbineTemperatureLabel = uilabel(app.InputsGridLayout);
            app.TurbineTemperatureLabel.Text = 'K';
            app.CompressorEfficiencyLabel = uilabel(app.InputsGridLayout);
            app.CompressorEfficiencyLabel.Text = '-';
            app.TurbineEfficiencyLabel = uilabel(app.InputsGridLayout);
            app.TurbineEfficiencyLabel.Text = '-';
            app.CombustorEfficiencyLabel = uilabel(app.InputsGridLayout);
            app.CombustorEfficiencyLabel.Text = '-';
            app.NozzlePressureRecoveryLabel = uilabel(app.InputsGridLayout);
            app.NozzlePressureRecoveryLabel.Text = '-';
            app.ResultsPanel = uipanel(app.MainGridLayout);
            app.ResultsPanel.Title = 'Cycle Analysis Results';
            app.ResultsGridLayout = uigridlayout(app.ResultsPanel);
            app.SummaryPanel = uipanel(app.ResultsGridLayout);
            app.SummaryPanel.Title = 'Performance Summary';
            app.SummaryGridLayout = uigridlayout(app.SummaryPanel);
            app.NetThrustLabel = uilabel(app.SummaryGridLayout);
            app.NetThrustLabel.Text = 'Net thrust';
            app.TSFCLabel = uilabel(app.SummaryGridLayout);
            app.TSFCLabel.Text = 'TSFC';
            app.NozzleConditionLabel = uilabel(app.SummaryGridLayout);
            app.NozzleConditionLabel.Text = 'Nozzle condition';
            app.ThrustValueLabel = uilabel(app.SummaryGridLayout);
            app.ThrustValueLabel.Text = '-';
            app.SpecificThrustLabel = uilabel(app.SummaryGridLayout);
            app.SpecificThrustLabel.Text = 'Specific thrust';
            app.FuelAirRatioLabel = uilabel(app.SummaryGridLayout);
            app.FuelAirRatioLabel.Text = 'Fuel-air ratio';
            app.ExitvelocityLabel = uilabel(app.SummaryGridLayout);
            app.ExitvelocityLabel.Text = 'Exit velocity';
            app.TSFCValueLabel = uilabel(app.SummaryGridLayout);
            app.TSFCValueLabel.Text = '-';
            app.NozzleStatusValueLabel = uilabel(app.SummaryGridLayout);
            app.NozzleStatusValueLabel.Text = '-';
            app.SpecificThrustValueLabel = uilabel(app.SummaryGridLayout);
            app.SpecificThrustValueLabel.Text = '-';
            app.FuelRatioValueLabel = uilabel(app.SummaryGridLayout);
            app.FuelRatioValueLabel.Text = '-';
            app.ExitVelocityValueLabel = uilabel(app.SummaryGridLayout);
            app.ExitVelocityValueLabel.Text = '-';
            app.ResultsTabGroup = uitabgroup(app.ResultsGridLayout);
            app.StationDataTab = uitab(app.ResultsTabGroup);
            app.StationDataTab.Title = 'Station Data';
            app.StationTable = uitable(app.StationDataTab);
            app.StationTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StationTable.RowName = {};
            app.PlotsTab = uitab(app.ResultsTabGroup);
            app.PlotsTab.Title = 'Plots';
            app.PerformanceAxes = uiaxes(app.PlotsTab);
            title(app.PerformanceAxes, 'Cycle Performance')
            xlabel(app.PerformanceAxes, 'Engine station')
            ylabel(app.PerformanceAxes, 'Total temperature [K]')
            zlabel(app.PerformanceAxes, 'Z')
            app.AboutTab = uitab(app.ResultsTabGroup);
            app.AboutTab.Title = 'Assumptions & Validation';
            app.TextAreaLabel = uilabel(app.AboutTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.AboutTextArea = uitextarea(app.AboutTab);
            app.AboutTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GasTurbineCycleAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end